classdef Perceptron_Lab1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1               matlab.ui.Figure
        Image4                matlab.ui.control.Image
        Image3                matlab.ui.control.Image
        text2                 matlab.ui.control.Label
        text11_2              matlab.ui.control.Label
        ArquitecturaRNALabel  matlab.ui.control.Label
        text4_2               matlab.ui.control.Label
        txtX2                 matlab.ui.control.EditField
        text11                matlab.ui.control.Label
        text10                matlab.ui.control.Label
        text9                 matlab.ui.control.Label
        text8                 matlab.ui.control.Label
        txtPesoEntradaX2      matlab.ui.control.EditField
        txtPesoEntradaX1      matlab.ui.control.EditField
        txtPesoBias           matlab.ui.control.EditField
        text7                 matlab.ui.control.Label
        txtSalida             matlab.ui.control.EditField
        btnEvaluarSalida      matlab.ui.control.Button
        txtX1                 matlab.ui.control.EditField
        text6                 matlab.ui.control.Label
        btnEntrenar           matlab.ui.control.Button
        txtErrorObjetivo      matlab.ui.control.EditField
        txtTasaAprendizaje    matlab.ui.control.EditField
        text4                 matlab.ui.control.Label
        btnInicializarPesos   matlab.ui.control.Button
        btnCargarPesos        matlab.ui.control.Button
        btnCargarSalidas      matlab.ui.control.Button
        btnCargarEntradas     matlab.ui.control.Button
        Image                 matlab.ui.control.Image
        Image2                matlab.ui.control.Image
        axesFrontera          matlab.ui.control.UIAxes
        axesPesos             matlab.ui.control.UIAxes
        axesError             matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        function data = cargarArchivotexto(app, tipo)
             % --- Función para cargar archivos ---

    % Seleccionar archivo .txt
            [file, path] = uigetfile('*.txt', sprintf('Selecciona el archivo de %s', tipo));
        
            % Verificar si se seleccionó un archivo
            if isequal(file, 0)
                data = [];  % Si no se selecciona archivo, devolver vacío
            else
                filePath = fullfile(path, file);   % Ruta completa
                data = load(filePath);             % Cargar datos
                assignin('base', tipo, data);      % Guardar en workspace de
                end
        end
        
        function W = generarPesosAleatoriosArchivo(app)
           % --- Función para generar los pesos aleatorios ---

            % Generar tres pesos aleatorios entre -1 y 1
            W = 2 * rand(1,3) - 1;  
            
            % Guardar en archivo de texto
            writematrix(W, 'pesos.txt', 'Delimiter', 'tab');  
            
            % Guardar en el workspace de MATLAB
            assignin('base', 'pesos', W);  
            
            % Mostrar mensaje en consola
            disp('Pesos generados y guardados en pesos.txt');
        end
        
        function slider1_CreateFcn(app, hObject, eventdata, handles)
            % --- Executes during object creation, after setting all properties.
            
            % hObject    handle to slider1 (see GCBO)
            % eventdata  reserved - to be defined in a future version of MATLAB
            % handles    empty - handles not created until after all CreateFcns called
            
            % Hint: slider controls usually have a light gray background.
            if isequal(get(hObject,'BackgroundColor'), get(0,'defaultUicontrolBackgroundColor'))
                set(hObject,'BackgroundColor',[.9 .9 .9]);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Perceptron_OpeningFcn(app, varargin)
            % --- Executes just before Perceptron is made visible.
            
            % Ensure that the app appears on screen when run
            movegui(app.figure1, 'onscreen');
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app); %#ok<ASGLU>
            image(x);
            colormap(map);
            axis off;
            hold on;
            
            handles.output = hObject;
            guidata(hObject, handles);
        end

        % Button pushed function: btnEvaluarSalida
        function btnEvaluarSalida_Callback(app, event)
            % --- Botón para calcular la salida ---
        
            % Acceder a los pesos definidos en el workspace
            global w
        
            % Leer entradas desde la interfaz
            x1 = str2double(app.txtX1.Value);
            x2 = str2double(app.txtX2.Value);
        
            % Construir vector de entrada con sesgo (x0 = 1)
            x_nuevo = [1; x1; x2];
        
            % Calcular la red neta
            net = w * x_nuevo;
        
            % Aplicar función de activación (escalón monopolar)
            y = double(net >= 0);
        
            % Mostrar resultado en el campo de salida
            app.txtSalida.Value = num2str(y);
        end

        % Button pushed function: btnCargarEntradas
        function btnCargarEntradas_Callback(app, event)
            % --- Botón para cargar el archivo de entradas
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            global x
                entradas = cargarArchivotexto(app, 'entradas');
                
                % Guardar en variable global trasponer
                x = entradas';
                
        end

        % Button pushed function: btnCargarPesos
        function btnCargarPesos_Callback(app, event)
            % --- Botón para cargar el archivo de pesos
            
            global w
                w = cargarArchivotexto(app, 'pesos');
        end

        % Button pushed function: btnCargarSalidas
        function btnCargarSalidas_Callback(app, event)
            % --- Botón para cargar el archivo de salidas
            
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                global yd;
            
                yd = cargarArchivotexto(app, 'salidas');
        end

        % Button pushed function: btnEntrenar
        function btnEntrenar_Callback(app, event)
           
    % --- Botón para entrenar el perceptrón
    
    % Create GUIDE-style callback args - Added by Migration Tool
    [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
    
    global w x yd epoca err error_history w_history error_deseado alfa

    % ========================== OBTENER PARÁMETROS DESDE LA GUI ==========================
    alfa = str2double(app.txtTasaAprendizaje.Value); % Tasa de aprendizaje
    error_deseado = str2double(app.txtErrorObjetivo.Value);% Error máximo permitido
    epoca = 0;                                                     % Contador de épocas
    err = ones(1,4);                                               % Vector inicial de errores
    error_history = [];                                            % Historial del error
    w_history = [];                                                % Historial de pesos

    % ========================== INICIALIZACIÓN DE PESOS ==========================
    if isfield(handles, 'pesos') && ~isempty(handles.pesos)
        w = handles.pesos;  % Recuperar pesos guardados
    else
        w = -1 + 2 * rand(1, 3); % Inicializar aleatoriamente en [-1,1]
    end

    guidata(hObject, handles); % Guardar cambios en la GUI

    % ========================== ENTRENAMIENTO PERCEPTRÓN ==========================
    while mean(err.^2) > error_deseado
        epoca = epoca + 1; % Aumentamos época

        for p = 1:4  % Recorrer los 4 patrones de entrada
            net = w * x(:,p);      % Potencial de activación
            y0 = net >= 0;         % Salida (función escalón)
            err(p) = yd(p) - y0;   % Error de la salida

            % Actualizar pesos solo si hay error
            if err(p) ~= 0
                w = w + alfa * err(p) * x(:,p)'; 
            end
        end

        % Guardar evolución
        error_history = [error_history; sum(abs(err))];
        w_history = [w_history; w];

        % ========================== GRÁFICAS ==========================

        % 1. Evolución del error
        axes(handles.axesError); cla;
        plot(1:epoca, error_history, '-or', 'LineWidth', 2, 'MarkerFaceColor','r');
        xlabel('Épocas'); ylabel('Error Absoluto');
        title('📉 Evolución del Error');
        grid on;

        % 2. Evolución de los pesos
        axes(handles.axesPesos); cla; hold on;
        plot(1:epoca, w_history(:,1), '-k', 'LineWidth', 2, 'DisplayName','w0 (umbral)');
        plot(1:epoca, w_history(:,2), '-b', 'LineWidth', 2, 'DisplayName','w1');
        plot(1:epoca, w_history(:,3), '-g', 'LineWidth', 2, 'DisplayName','w2');
        xlabel('Épocas'); ylabel('Valor de los Pesos');
        title( 'Evolucion de los Pesos');
        legend('show'); grid on; hold off;

        % 3. Frontera de decisión y patrones
        axes(handles.axesFrontera); cla; hold on;

        % Graficar los patrones
        for p = 1:4
            if yd(p) == 1
                plot(x(2,p), x(3,p), 'go', 'MarkerSize', 10, 'MarkerFaceColor','g', 'DisplayName','Clase 1');
            else
                plot(x(2,p), x(3,p), 'ro', 'MarkerSize', 10, 'MarkerFaceColor','r', 'DisplayName','Clase 0');
            end
        end

        % Graficar frontera de decisión
        if w(3) ~= 0
            x_vals = -0.5:0.01:1.5; % Valores de X1
            y_vals = (-w(1) - w(2) * x_vals) / w(3);
            plot(x_vals, y_vals, 'b-', 'LineWidth', 2, 'DisplayName','Frontera de decisión');
        else
            disp('Advertencia: w3 = 0, no se puede graficar la frontera.');
        end

        xlabel('X1'); ylabel('X2');
        xlim([-0.5, 1.5]); ylim([-0.5, 1.5]);
        title('🔎 Patrones y Frontera de Decisión');
        grid on; hold off; drawnow;
    end

    % ========================== GUARDAR RESULTADOS ==========================
    writematrix(w, 'pesos_finales.txt', 'Delimiter', 'tab'); % Guardar pesos en archivo
    handles.pesos = w;                                       % Guardar en GUI
    guidata(hObject, handles);                               % Actualizar datos
    assignin('base', 'pesos', w);                            % Guardar en workspace
    disp('Entrenamiento finalizado.');
    disp(['Número total de épocas: ', num2str(epoca)]);

        end

        % Button pushed function: btnInicializarPesos
        function btnInicializarPesos_Callback(app, event)
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event);
    
        try
            % Generar pesos aleatorios usando función externa
            pesosAleatorios = generarPesosAleatoriosArchivo(app);
            
            % Actualizar las cajas de texto con los valores generados
            set(handles.txtPesoBias, 'String', num2str(pesosAleatorios(1), '%.4f'));      % Peso del bias
            set(handles.txtPeso2, 'String', num2str(pesosAleatorios(2), '%.4f')); % Peso de entrada X1
            set(handles.txtPeso3, 'String', num2str(pesosAleatorios(3), '%.4f')); % Peso de entrada X2

            % Guardar los pesos en la estructura handles
            handles.pesos = pesosAleatorios;
            guidata(hObject, handles);
            
            % Guardar en workspace base
            assignin('base', 'pesos', handles.pesos);
            
            % Mensaje informativo
            fprintf('Pesos aleatorios generados exitosamente:\n');
            fprintf('  Bias:        %.4f\n', pesosAleatorios(1));
            fprintf('  Entrada X1:  %.4f\n', pesosAleatorios(2));
            fprintf('  Entrada X2:  %.4f\n', pesosAleatorios(3));
            
        catch ME
            % Manejo de errores
            errordlg(['Error al generar pesos aleatorios: ' ME.message], 'Error de Generación');
            fprintf('Error en generación de pesos: %s\n', ME.message);
        end
        end

        % Value changed function: txtTasaAprendizaje
        function txtTasaAprendizaje_Callback(app, event)
                 
                        [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            valorIngresado = str2double(get(hObject, 'String')); % Obtener valor del cuadro de texto
            
                if isnan(valorIngresado) || valorIngresado <= 0
                    errordlg('Ingrese un valor válido para el coeficiente de aprendizaje (mayor a 0)', 'Error');
                    valorIngresado = 0.1; % Valor por defecto
                    set(hObject, 'String', num2str(valorIngresado)); % Restaurar en la caja de texto

            end
        end

        % Value changed function: txtErrorObjetivo
        function txtErrorObjetivo_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                valorIngresado = str2double(get(hObject, 'String'));
    
                if ~isnan(valorIngresado) && valorIngresado > 0
                    handles.error_deseado = valorIngresado; % Actualizar error deseado
                    guidata(hObject, handles); % Actualizar datos en la GUI
                    
                    fprintf('Error objetivo establecido: %.6f\n', valorIngresado);
                else
                    % Mostrar error si el valor no es válido
                    errordlg('Por favor, ingrese un valor numérico positivo para el error objetivo.', ...
                            'Error de Entrada');
                    
                    % Establecer valor por defecto
                    valorDefecto = 0.001;
                    handles.error_deseado = valorDefecto;
                    set(hObject, 'String', num2str(valorDefecto));
                    guidata(hObject, handles);
                end
        end

        % Value changed function: txtPesoEntradaX1
        function txtPesoEntradaX1_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
            % Obtener y validar el valor ingresado
    valorIngresado = str2double(get(hObject, 'String'));
    
    if ~isnan(valorIngresado) % Verificar que sea un número válido
        handles.pesos(2) = valorIngresado; % Actualizar peso de x1 (posición 2)
        assignin('base', 'pesos', handles.pesos); % Guardar en workspace base
        guidata(hObject, handles); % Actualizar datos en la GUI
        
        fprintf('Peso de entrada X1 actualizado: %.4f\n', valorIngresado);
    else
        % Mostrar error si el valor no es válido
        errordlg('Por favor, ingrese un valor numérico válido para el peso de la entrada X1.', ...
                'Error de Entrada');
        
        % Restaurar valor anterior
        set(hObject, 'String', num2str(handles.pesos(2)));
                end
        end

        % Value changed function: txtPesoEntradaX2
        function txtPesoEntradaX2_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            % Obtener y validar el valor ingresado
            valorIngresado = str2double(get(hObject, 'String'));
            
                if ~isnan(valorIngresado) % Verificar que sea un número válido
                handles.pesos(3) = valorIngresado; % Actualizar peso de x2 (posición 3)
                assignin('base', 'pesos', handles.pesos); % Guardar en workspace base
                guidata(hObject, handles); % Actualizar datos en la GUI
                
                fprintf('Peso de entrada X2 actualizado: %.4f\n', valorIngresado);
            else
                % Mostrar error si el valor no es válido
                errordlg('Por favor, ingrese un valor numérico válido para el peso de la entrada X2.', ...
                        'Error de Entrada');
                
                % Restaurar valor anterior
                set(hObject, 'String', num2str(handles.pesos(3)));
                   
                end
        
        end

        % Value changed function: txtPesoBias
        function txtPesoBias_Callback(app, event)
            % Create GUIDE-style callback args - Added by Migration Tool
            [hObject, eventdata, handles] = convertToGUIDECallbackArguments(app, event); %#ok<ASGLU>
            
                valorIngresado = str2double(get(hObject, 'String'));
                  if ~isnan(valorIngresado) % Verificar que sea un número válido
                    handles.pesos(1) = valorIngresado; % Actualizar peso del bias (posición 1)
                    assignin('base', 'pesos', handles.pesos); % Guardar en workspace base
                    guidata(hObject, handles); % Actualizar datos en la GUI
                    
                    fprintf('Peso del bias actualizado: %.4f\n', valorIngresado);
                else
                    % Mostrar error si el valor no es válido
                    errordlg('Por favor, ingrese un valor numérico válido para el peso del bias.', ...
                            'Error de Entrada');
                    
                    % Restaurar valor anterior
                    set(hObject, 'String', num2str(handles.pesos(1)));
                  end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Perceptron';
            app.figure1.Tag = 'figure1';
            app.axesError = uiaxes(app.figure1);
            app.axesError.Tag = 'axesError';
            app.axesPesos = uiaxes(app.figure1);
            app.axesPesos.Tag = 'axesPesos';
            app.axesFrontera = uiaxes(app.figure1);
            app.axesFrontera.Tag = 'axesFrontera';
            app.Image2 = uiimage(app.figure1);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'FONDO RNA.jpg');
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'RNA.jpg');
            app.btnCargarEntradas = uibutton(app.figure1, 'push');
            app.btnCargarEntradas.ButtonPushedFcn = createCallbackFcn(app, @btnCargarEntradas_Callback, true);
            app.btnCargarEntradas.Tag = 'btnCargarEntradas';
            app.btnCargarEntradas.Text = 'Cargar Entradas';
            app.btnCargarSalidas = uibutton(app.figure1, 'push');
            app.btnCargarSalidas.ButtonPushedFcn = createCallbackFcn(app, @btnCargarSalidas_Callback, true);
            app.btnCargarSalidas.Tag = 'btnCargarSalidas';
            app.btnCargarSalidas.Text = 'Cargar Salidas';
            app.btnCargarPesos = uibutton(app.figure1, 'push');
            app.btnCargarPesos.ButtonPushedFcn = createCallbackFcn(app, @btnCargarPesos_Callback, true);
            app.btnCargarPesos.Tag = 'btnCargarPesos';
            app.btnCargarPesos.Text = 'Cargar Pesos';
            app.btnInicializarPesos = uibutton(app.figure1, 'push');
            app.btnInicializarPesos.ButtonPushedFcn = createCallbackFcn(app, @btnInicializarPesos_Callback, true);
            app.btnInicializarPesos.Tag = 'btnGenerarPesos';
            app.btnInicializarPesos.Text = 'Generar Pesos';
            app.text4 = uilabel(app.figure1);
            app.text4.Tag = 'text4';
            app.text4.Text = 'Tasa de aprendizaje (α):';
            app.txtTasaAprendizaje = uieditfield(app.figure1, 'text');
            app.txtTasaAprendizaje.ValueChangedFcn = createCallbackFcn(app, @txtTasaAprendizaje_Callback, true);
            app.txtTasaAprendizaje.Tag = 'txtAlfa';
            app.txtErrorObjetivo = uieditfield(app.figure1, 'text');
            app.txtErrorObjetivo.ValueChangedFcn = createCallbackFcn(app, @txtErrorObjetivo_Callback, true);
            app.txtErrorObjetivo.Tag = 'txtErrorDeseado';
            app.btnEntrenar = uibutton(app.figure1, 'push');
            app.btnEntrenar.ButtonPushedFcn = createCallbackFcn(app, @btnEntrenar_Callback, true);
            app.btnEntrenar.Tag = 'btnEntrenar';
            app.btnEntrenar.Text = 'Entrenar';
            app.text6 = uilabel(app.figure1);
            app.text6.Tag = 'text6';
            app.text6.Text = 'Entradas';
            app.txtX1 = uieditfield(app.figure1, 'text');
            app.txtX1.Tag = 'txtX1';
            app.btnEvaluarSalida = uibutton(app.figure1, 'push');
            app.btnEvaluarSalida.ButtonPushedFcn = createCallbackFcn(app, @btnEvaluarSalida_Callback, true);
            app.btnEvaluarSalida.Tag = 'btnCalcularSalida';
            app.btnEvaluarSalida.Text = 'Calcular Salida';
            app.txtSalida = uieditfield(app.figure1, 'text');
            app.txtSalida.Tag = 'txtSalida';
            app.text7 = uilabel(app.figure1);
            app.text7.Tag = 'text7';
            app.text7.Text = 'Salida';
            app.txtPesoBias = uieditfield(app.figure1, 'text');
            app.txtPesoBias.ValueChangedFcn = createCallbackFcn(app, @txtPesoBias_Callback, true);
            app.txtPesoBias.Tag = 'txtPesoBias';
            app.txtPesoEntradaX1 = uieditfield(app.figure1, 'text');
            app.txtPesoEntradaX1.ValueChangedFcn = createCallbackFcn(app, @txtPesoEntradaX1_Callback, true);
            app.txtPesoEntradaX1.Tag = 'txtPeso2';
            app.txtPesoEntradaX2 = uieditfield(app.figure1, 'text');
            app.txtPesoEntradaX2.ValueChangedFcn = createCallbackFcn(app, @txtPesoEntradaX2_Callback, true);
            app.txtPesoEntradaX2.Tag = 'txtPeso3';
            app.text8 = uilabel(app.figure1);
            app.text8.Tag = 'text8';
            app.text8.Text = 'w0';
            app.text9 = uilabel(app.figure1);
            app.text9.Tag = 'text9';
            app.text9.Text = 'w1';
            app.text10 = uilabel(app.figure1);
            app.text10.Tag = 'text10';
            app.text10.Text = 'w2';
            app.text11 = uilabel(app.figure1);
            app.text11.Tag = 'text11';
            app.text11.Text = 'Cargar pesos de un txt';
            app.txtX2 = uieditfield(app.figure1, 'text');
            app.txtX2.Tag = 'txtX2';
            app.text4_2 = uilabel(app.figure1);
            app.text4_2.Text = 'Error deseado';
            app.ArquitecturaRNALabel = uilabel(app.figure1);
            app.ArquitecturaRNALabel.Text = 'Arquitectura RNA';
            app.text11_2 = uilabel(app.figure1);
            app.text11_2.Tag = 'text11';
            app.text11_2.Text = 'Cargar pesos de manera aleatoria';
            app.text2 = uilabel(app.figure1);
            app.text2.Tag = 'text2';
            app.text2.Text = {'Integrantes:'; ''; 'YISELA KATERINE FORERO SILVA'; 'JOHAN ARLEY SIERRA LOPEZ'; 'ANDRES FELI´PE DIAZ CAMPOS'};
            app.Image3 = uiimage(app.figure1);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'Imagen3.png');
            app.Image4 = uiimage(app.figure1);
            app.Image4.ImageSource = fullfile(pathToMLAPP, 'logo UNIVERSIDAD.png');
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Perceptron_Lab1(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.figure1)

                % Execute the startup function
                runStartupFcn(app, @(app)Perceptron_OpeningFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.figure1)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end